classdef RiskFactorTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlotAxes                      matlab.ui.control.UIAxes
        RankLabel2                    matlab.ui.control.Label
        ChooseCountryRegiontoAnalyzeLabel  matlab.ui.control.Label
        ChooseCountryDropDown         matlab.ui.control.DropDown
        ChooseCategorytoAnalyzeLabel  matlab.ui.control.Label
        CategoryDropDown              matlab.ui.control.DropDown
        IncludeAverageCheckBox        matlab.ui.control.CheckBox
        RankDropDown1                 matlab.ui.control.DropDown
        RankDropDown2                 matlab.ui.control.DropDown
        RankLabel                     matlab.ui.control.Label
        Outofthe231CountriesRegionsinLabel  matlab.ui.control.Label
        andLabel                      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        PieChartAxes                  matlab.ui.control.UIAxes
        PieChartYearOneDropDown       matlab.ui.control.DropDown
        PieChartYearTwoDropDown       matlab.ui.control.DropDown
        PercentagesofDeathsFromStudiedRiskFactorsForLabel  matlab.ui.control.Label
        CountryNameLabel              matlab.ui.control.Label
        BetweentheYearsLabel          matlab.ui.control.Label
        andLabel_2                    matlab.ui.control.Label
        CountryNameLabel_2            matlab.ui.control.Label
        PieChartLabel                 matlab.ui.control.Label
        RankingSystemLabel            matlab.ui.control.Label
        CategoryLabel                 matlab.ui.control.Label
        BetweentheYearsLabel_2        matlab.ui.control.Label
        GraphLabel                    matlab.ui.control.Label
        CurrentlyGraphingLabel        matlab.ui.control.Label
        CategoryLabel_2               matlab.ui.control.Label
        DataForLabel                  matlab.ui.control.Label
        CountryLabel                  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        IncludeTheAverageOfLabel      matlab.ui.control.Label
        CategoryLabel_3               matlab.ui.control.Label
        DataForAll231CountriesRegionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             ProgrammingProject(app);
        end

        % Value changed function: RankDropDown1
        function RankDropDown1ValueChanged(app, event)
            % if statement is here in order to make sure that year range
            % entered by the user makes chronological sense
            if str2double(app.RankDropDown2.Value) >= str2double(app.RankDropDown1.Value)
                ProgrammingProject(app);
                app.Label_3.Text = '';
            else
                app.Label_3.Text = 'Invalid Year Range Entry!';
            end
            
        end

        % Value changed function: RankDropDown2
        function RankDropDown2ValueChanged(app, event)
             % if statement is here in order to make sure that year range
            % entered by the user makes chronological sense
            if str2double(app.RankDropDown2.Value) >= str2double(app.RankDropDown1.Value)
                ProgrammingProject(app);
                app.Label_3.Text = '';
            else
                app.Label_3.Text = 'Invalid Year Range Entry!';
            end
            
        end

        % Value changed function: ChooseCountryDropDown
        function ChooseCountryDropDownValueChanged(app, event)
            ProgrammingProject(app);
            
        end

        % Value changed function: IncludeAverageCheckBox
        function IncludeAverageCheckBoxValueChanged(app, event)
            ProgrammingProject(app);
            
        end

        % Value changed function: CategoryDropDown
        function CategoryDropDownValueChanged(app, event)
            ProgrammingProject(app);
            
        end

        % Callback function
        function AverageRateYearDropDown1ValueChanged(app, event)
            ProgrammingProject(app);
            
        end

        % Callback function
        function AverageRateYearDropDown2ValueChanged(app, event)
            ProgrammingProject(app);
            
        end

        % Callback function
        function ThreeDBarGraphCountryDropDownValueChanged(app, event)
            ProgrammingProject(app);
            
        end

        % Value changed function: PieChartYearOneDropDown
        function PieChartYearOneDropDownValueChanged(app, event)
             % if statement is here in order to make sure that year range
            % entered by the user makes chronological sense
            if str2double(app.PieChartYearTwoDropDown.Value) >= str2double(app.PieChartYearOneDropDown.Value)
                ProgrammingProject(app);
                app.Label_4.Text = '';
            else
                app.Label_4.Text = 'Invalid Year Range Entry!';
            end
            
        end

        % Value changed function: PieChartYearTwoDropDown
        function PieChartYearTwoDropDownValueChanged(app, event)
              % if statement is here in order to make sure that year range
            % entered by the user makes chronological sense
            if str2double(app.PieChartYearTwoDropDown.Value) >= str2double(app.PieChartYearOneDropDown.Value)
                ProgrammingProject(app);
                app.Label_4.Text = '';
            else
                app.Label_4.Text = 'Invalid Year Range Entry!';
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'Title')
            xlabel(app.PlotAxes, 'Year')
            ylabel(app.PlotAxes, 'Deaths Per 100000')
            app.RankLabel2 = uilabel(app.UIFigure);
            app.RankLabel2.Text = 'is Rank ';
            app.ChooseCountryRegiontoAnalyzeLabel = uilabel(app.UIFigure);
            app.ChooseCountryRegiontoAnalyzeLabel.Text = {'Choose Country/Region '; 'to Analyze:'};
            app.ChooseCountryDropDown = uidropdown(app.UIFigure);
            app.ChooseCountryDropDown.Items = {'Afghanistan', 'Albania', 'Algeria', 'American Samoa', 'Andean Latin America', 'Andorra', 'Angola', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Australasia', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bermuda', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burundi', 'Cambodia', 'Cameroon', 'Canada', 'Cape Verde', 'Caribbean', 'Central African Republic', 'Central Asia', 'Central Europe', 'Central Europe, Eastern Europe, and Central Asia', 'Central Latin America', 'Central Sub-Saharan Africa', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo', 'Costa Rica', 'Cote d''Ivoire', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Democratic Republic of Congo', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'East Asia', 'Eastern Europe', 'Eastern Sub-Saharan Africa', 'Ecuador', 'Egypt', 'El Salvador', 'England', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Greenland', 'Grenada', 'Guam', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'High SDI', 'High-income', 'High-income Asia Pacific', 'High-middle SDI', 'Honduras', 'Hungary', 'Iceland', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kiribati', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latin America and Caribbean', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Lithuania', 'Low SDI', 'Low-middle SDI', 'Luxembourg', 'Macedonia', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Marshall Islands', 'Mauritania', 'Mauritius', 'Mexico', 'Micronesia (country)', 'Middle SDI', 'Moldova', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Myanmar', 'Namibia', 'Nepal', 'Netherlands', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North Africa and Middle East', 'North America', 'North Korea', 'Northern Ireland', 'Northern Mariana Islands', 'Norway', 'Oceania', 'Oman', 'Pakistan', 'Palestine', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Rwanda', 'Saint Lucia', 'Saint Vincent and the Grenadines', 'Samoa', 'Sao Tome and Principe', 'Saudi Arabia', 'Scotland', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Slovakia', 'Slovenia', 'Solomon Islands', 'Somalia', 'South Africa', 'South Asia', 'South Korea', 'South Sudan', 'Southeast Asia', 'Southeast Asia, East Asia, and Oceania', 'Southern Latin America', 'Southern Sub-Saharan Africa', 'Spain', 'Sri Lanka', 'Sub-Saharan Africa', 'Sudan', 'Suriname', 'Swaziland', 'Sweden', 'Switzerland', 'Syria', 'Taiwan', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tropical Latin America', 'Tunisia', 'Turkey', 'Turkmenistan', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States', 'United States Virgin Islands', 'Uruguay', 'Uzbekistan', 'Vanuatu', 'Venezuela', 'Vietnam', 'Wales', 'Western Europe', 'Western Sub-Saharan Africa', 'World', 'Yemen', 'Zambia', 'Zimbabwe', ''};
            app.ChooseCountryDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseCountryDropDownValueChanged, true);
            app.ChooseCountryDropDown.Value = 'Afghanistan';
            app.ChooseCategorytoAnalyzeLabel = uilabel(app.UIFigure);
            app.ChooseCategorytoAnalyzeLabel.Text = {'Choose Category'; 'to Analyze:'};
            app.CategoryDropDown = uidropdown(app.UIFigure);
            app.CategoryDropDown.Items = {'Total Deaths', 'Obesity Deaths', 'Drug Deaths', 'Alcohol Deaths', 'Smoking Deaths'};
            app.CategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoryDropDownValueChanged, true);
            app.CategoryDropDown.Value = 'Total Deaths';
            app.IncludeAverageCheckBox = uicheckbox(app.UIFigure);
            app.IncludeAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeAverageCheckBoxValueChanged, true);
            app.IncludeAverageCheckBox.Text = '';
            app.RankDropDown1 = uidropdown(app.UIFigure);
            app.RankDropDown1.Items = {'1990', '1991', '1992', '1993', '1994', '1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', ''};
            app.RankDropDown1.ValueChangedFcn = createCallbackFcn(app, @RankDropDown1ValueChanged, true);
            app.RankDropDown1.Value = '1990';
            app.RankDropDown2 = uidropdown(app.UIFigure);
            app.RankDropDown2.Items = {'1990', '1991', '1992', '1993', '1994', '1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', ''};
            app.RankDropDown2.ValueChangedFcn = createCallbackFcn(app, @RankDropDown2ValueChanged, true);
            app.RankDropDown2.Value = '1990';
            app.RankLabel = uilabel(app.UIFigure);
            app.RankLabel.Text = 'x';
            app.Outofthe231CountriesRegionsinLabel = uilabel(app.UIFigure);
            app.Outofthe231CountriesRegionsinLabel.Text = 'Out of the 231 Countries/Regions in';
            app.andLabel = uilabel(app.UIFigure);
            app.andLabel.Text = 'and';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Note About Ranking System:'; ' '; 'Rank 1 = Lowest Number of Deaths'; 'Per 100,000 of All 231 Countries/Regions.'; ''; 'Rank 231 = Highest Number of Deaths'; 'Per 100,000 of All 231 Countries/Regions.'};
            app.PieChartAxes = uiaxes(app.UIFigure);
            title(app.PieChartAxes, 'Title')
            xlabel(app.PieChartAxes, 'X')
            ylabel(app.PieChartAxes, 'Y')
            app.PieChartYearOneDropDown = uidropdown(app.UIFigure);
            app.PieChartYearOneDropDown.Items = {'1990', '1991', '1992', '1993', '1994', '1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017'};
            app.PieChartYearOneDropDown.ValueChangedFcn = createCallbackFcn(app, @PieChartYearOneDropDownValueChanged, true);
            app.PieChartYearOneDropDown.Value = '1990';
            app.PieChartYearTwoDropDown = uidropdown(app.UIFigure);
            app.PieChartYearTwoDropDown.Items = {'1990', '1991', '1992', '1993', '1994', '1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017'};
            app.PieChartYearTwoDropDown.ValueChangedFcn = createCallbackFcn(app, @PieChartYearTwoDropDownValueChanged, true);
            app.PieChartYearTwoDropDown.Value = '1990';
            app.PercentagesofDeathsFromStudiedRiskFactorsForLabel = uilabel(app.UIFigure);
            app.PercentagesofDeathsFromStudiedRiskFactorsForLabel.Text = {'Percentages of Deaths '; 'From Studied Risk Factors For'};
            app.CountryNameLabel = uilabel(app.UIFigure);
            app.CountryNameLabel.Text = 'Country Name';
            app.BetweentheYearsLabel = uilabel(app.UIFigure);
            app.BetweentheYearsLabel.Text = 'Between the Years';
            app.andLabel_2 = uilabel(app.UIFigure);
            app.andLabel_2.Text = 'and';
            app.CountryNameLabel_2 = uilabel(app.UIFigure);
            app.CountryNameLabel_2.Text = 'Country Name';
            app.PieChartLabel = uilabel(app.UIFigure);
            app.PieChartLabel.Text = 'Pie Chart:';
            app.RankingSystemLabel = uilabel(app.UIFigure);
            app.RankingSystemLabel.Text = 'Ranking System:';
            app.CategoryLabel = uilabel(app.UIFigure);
            app.BetweentheYearsLabel_2 = uilabel(app.UIFigure);
            app.BetweentheYearsLabel_2.Text = 'Between the Years';
            app.GraphLabel = uilabel(app.UIFigure);
            app.GraphLabel.Text = 'Graph:';
            app.CurrentlyGraphingLabel = uilabel(app.UIFigure);
            app.CurrentlyGraphingLabel.Text = 'Currently Graphing';
            app.CategoryLabel_2 = uilabel(app.UIFigure);
            app.CategoryLabel_2.Text = 'Category';
            app.DataForLabel = uilabel(app.UIFigure);
            app.DataForLabel.Text = 'Data For';
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Note:'; 'If the Year Range You'; 'Enter Does Not Make'; 'Chronological Sense,'; 'The Program Will Not'; 'Change Its Visualizations'; 'To Be In Accordance'; 'With Those Years.'};
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = {'Note:'; 'If the Year Range You Enter Does Not'; 'Make Chronological Sense,'; 'The Program Will Not Change Its'; 'Visualizations To Be In Accordance With Those Years.'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.IncludeTheAverageOfLabel = uilabel(app.UIFigure);
            app.IncludeTheAverageOfLabel.Text = 'Include The Average Of';
            app.CategoryLabel_3 = uilabel(app.UIFigure);
            app.CategoryLabel_3.Text = 'Category';
            app.DataForAll231CountriesRegionsLabel = uilabel(app.UIFigure);
            app.DataForAll231CountriesRegionsLabel.Text = 'Data For All 231 Countries/Regions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RiskFactorTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end